classdef Interfaz_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Cargar                          matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        AudioMixerLabel                 matlab.ui.control.Label
        Play                            matlab.ui.control.Button
        Pause                           matlab.ui.control.Button
        Resume                          matlab.ui.control.Button
        Stop                            matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        Canal1Label                     matlab.ui.control.Label
        Canal2Label                     matlab.ui.control.Label
        EfectoDropDownLabel             matlab.ui.control.Label
        EfectoDropDown                  matlab.ui.control.DropDown
        EfectoDropDown_2Label           matlab.ui.control.Label
        EfectoDropDown_2                matlab.ui.control.DropDown
        SwitchEfecto1                   matlab.ui.control.Switch
        SwitchEfecto2                   matlab.ui.control.Switch
        GananciaSpinnerLabel            matlab.ui.control.Label
        Ganancia1                       matlab.ui.control.Spinner
        NuevoarchivoEditFieldLabel      matlab.ui.control.Label
        NuevoarchivoEditField           matlab.ui.control.EditField
        GenerarButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        GenerarButton_2                 matlab.ui.control.Button
        GuardarVariablesEditFieldLabel  matlab.ui.control.Label
        GuardarVariablesEditField       matlab.ui.control.EditField
        matLabel                        matlab.ui.control.Label
        wavLabel                        matlab.ui.control.Label
        GenerarSealdelFiltroButton      matlab.ui.control.Button
        GenerarSealdeEntradaButton      matlab.ui.control.Button
        GenerarSealdeSalidaButton       matlab.ui.control.Button
        AplicarCambiosButton            matlab.ui.control.Button
        AplicarCambiosButton_2          matlab.ui.control.Button
        SwitchFiltro1                   matlab.ui.control.Switch
        SwitchFiltro2                   matlab.ui.control.Switch
        GananciaSpinner_2Label          matlab.ui.control.Label
        Ganancia2                       matlab.ui.control.Spinner
        FrecuenciadecorteSpinner_2Label  matlab.ui.control.Label
        Frecuenciadecorte2              matlab.ui.control.Spinner
        FrecuenciadecorteSpinnerLabel   matlab.ui.control.Label
        Frecuenciadecorte1              matlab.ui.control.Spinner
        AlfaLabel                       matlab.ui.control.Label
        Alfa                            matlab.ui.control.Spinner
        DelaySliderLabel                matlab.ui.control.Label
        Delay                           matlab.ui.control.Slider
    end

    
    properties (Access = public)
        archivo     % Description
        player
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Cargar
        function CargarButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav;*.mp3', 'Audio Files'});
            if isequal(file,0)
                app.EditField.BackgroundColor = 'red';
            else
                app.EditField.BackgroundColor = 'white';
                app.EditField.Value = file;
                app.archivo = file;
                [audio,Fs] = audioread(app.archivo);
                app.player = audioplayer(audio,Fs);
            end
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            play(app.player);
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: Resume
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Button pushed function: GenerarSealdeEntradaButton
        function GenerarSealdeEntradaButtonPushed(app, event)
            [x,~] = audioread(app.archivo);
            t1 = (0:(1/(length(x)-1)):1);
            plot(app.UIAxes_2, t1,x);
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            [x_new,Fs_new] = audioread(app.archivo);
            Nuevo_archivo = app.NuevoarchivoEditField.Value+".wav";
            audiowrite(Nuevo_archivo, x_new, Fs_new);
        end

        % Button pushed function: AplicarCambiosButton
        function AplicarCambiosButtonPushed(app, event)
            val_fil1 = app.SwitchFiltro1.Value;
            val_fil2 = app.SwitchFiltro2.Value;
            
            val_efe1 = app.SwitchEfecto1.Value;
            val_efe2 = app.SwitchEfecto2.Value;
            
            fil_sel = app.FiltroDropDown.Value;
            efe_sel = app.EfectoDropDown.Value;
            f_corte = app.Frecuenciadecorte1.Value;
            gan=app.Ganancia1.Value;
            
            [x,Fs] = audioread(app.archivo);
            
            if (strcmp(fil_sel, 'Pasabajas') && strcmp(val_fil1, 'On' ))
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'red';

            elseif (strcmp(fil_sel, 'Pasa-altas') && strcmp(val_fil1, 'On'))
                    x_edit = gan*highpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'green';

            elseif (strcmp(fil_sel, 'Pasabandas')  &&  strcmp(val_fil1, 'On'))
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';

            elseif (strcmp(fil_sel, 'Notch') && strcmp(val_fil1, 'On'))
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';

                
            elseif (strcmp(efe_sel, 'Eco')  && strcmp(val_efe1, 'On'))
                    alfa = 0.5;
                    D2 = app.Delay.Value;
                    x_edit = zeros(size(x));
                    x2_n_D = 0;
                    y2_n_D = 0;
                    j = 0;
                    delay2 = j-D2;
                    N = length(x);
                    for (j = 1:N)
                        x_edit(j) = x(j) + (1-alfa).*x2_n_D + alfa.*y2_n_D;
                        if (j-11025 > 0)
                            x2_n_D = x(j-D2);
                            y2_n_D = x_edit(j-D2);
                        end
                    end
            else
                x_edit = x;
                app.Ganancia1.BackgroundColor = 'red';
            end
            app.player = audioplayer(x_edit,Fs);
            t2 = (0:(1/(length(x_edit)-1)):1);
            plot(app.UIAxes, t2,x_edit);
        end

        % Button pushed function: AplicarCambiosButton_2
        function AplicarCambiosButton_2Pushed(app, event)
            val_fil1 = app.SwitchFiltro1.Value;
            val_fil2 = app.SwitchFiltro2.Value;
            
            val_efe1 = app.SwitchEfecto1.Value;
            val_efe2 = app.SwitchEfecto2.Value;
            
            fil_sel = app.FiltroDropDown.Value;
            efe_sel = app.EfectoDropDown.Value;
            f_corte = app.Frecuenciadecorte1.Value;
            gan=app.Ganancia1.Value;
            
            [x,Fs] = audioread(app.archivo);
            
            if strcmp(fil_sel, 'Pasabajas') 
                if strcmp(val_fil1, 'On' )
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'red';
                else
                   x_edit = x;
                   app.Ganancia1.BackgroundColor = 'black'; 
                end
            elseif strcmp(fil_sel, 'Pasa-altas')
                if strcmp(val_fil1, 'On')
                    x_edit = gan*highpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'green';
                else
                    x_edit = x;
                   app.Ganancia1.BackgroundColor = 'black';
                end
            elseif strcmp(fil_sel, 'Pasabandas')    
                if strcmp(val_fil1, 'On')
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';
                else
                    x_edit = x;
                   app.Ganancia1.BackgroundColor = 'black';
                end

            elseif strcmp(fil_sel, 'Notch')    
                if strcmp(val_fil1, 'On')
                    x_edit = gan*lowpass(x,f_corte,Fs);
                    app.Ganancia1.BackgroundColor = 'blue';
                else
                    x_edit = x;
                   app.Ganancia1.BackgroundColor = 'black';
                end
                
            elseif strcmp(efe_sel, 'Eco')    
                if strcmp(val_efe1, 'On')
                    alfa = 0.5;
                    D2 = 11025;
                    x_edit = zeros(size(x));
                    x2_n_D = 0;
                    y2_n_D = 0;
                    j = 0;
                    delay2 = j-D2;
                    N = length(x);
                    for (j = 1:N)
                        x_edit(j) = x(j) + (1-alfa).*x2_n_D + alfa.*y2_n_D;
                        if (j-11025 > 0)
                            x2_n_D = x(j-D2);
                            y2_n_D = x_edit(j-D2);
                        end
                    end

                else
                    x_edit = x;
                   app.Ganancia1.BackgroundColor = 'black';
                end
            else
                x_edit = x;
                app.Ganancia1.BackgroundColor = 'purple';
            end
            app.player = audioplayer(x_edit,Fs);
            t2 = (0:(1/(length(x_edit)-1)):1);
            plot(app.UIAxes, t2,x_edit);
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Cargar = uibutton(app.UIFigure, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.Cargar.Icon = 'upload.png';
            app.Cargar.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.AudioMixerLabel = uilabel(app.UIFigure);
            app.AudioMixerLabel.Text = 'Audio Mixer';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'play.png';
            app.Play.Text = '';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Icon = 'pause.png';
            app.Pause.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.Resume.Icon = 'resume.png';
            app.Resume.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'stop.png';
            app.Stop.Text = '';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown.Value = 'Pasa-altas';
            app.FiltroDropDown_2Label = uilabel(app.UIFigure);
            app.FiltroDropDown_2Label.Text = 'Filtro';
            app.FiltroDropDown_2 = uidropdown(app.UIFigure);
            app.FiltroDropDown_2.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown_2.Value = 'Pasa-altas';
            app.Canal1Label = uilabel(app.UIFigure);
            app.Canal1Label.Text = 'Canal 1';
            app.Canal2Label = uilabel(app.UIFigure);
            app.Canal2Label.Text = 'Canal 2';
            app.EfectoDropDownLabel = uilabel(app.UIFigure);
            app.EfectoDropDownLabel.Text = 'Efecto';
            app.EfectoDropDown = uidropdown(app.UIFigure);
            app.EfectoDropDown.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown.Value = 'Eco';
            app.EfectoDropDown_2Label = uilabel(app.UIFigure);
            app.EfectoDropDown_2Label.Text = 'Efecto';
            app.EfectoDropDown_2 = uidropdown(app.UIFigure);
            app.EfectoDropDown_2.Items = {'Eco', 'Vibrato', 'Distorsión', 'Reverberación', 'Flanger'};
            app.EfectoDropDown_2.Value = 'Eco';
            app.SwitchEfecto1 = uiswitch(app.UIFigure, 'slider');
            app.SwitchEfecto2 = uiswitch(app.UIFigure, 'slider');
            app.GananciaSpinnerLabel = uilabel(app.UIFigure);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.Ganancia1 = uispinner(app.UIFigure);
            app.Ganancia1.Step = 0.5;
            app.Ganancia1.Value = 1;
            app.NuevoarchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NuevoarchivoEditFieldLabel.Text = 'Nuevo archivo';
            app.NuevoarchivoEditField = uieditfield(app.UIFigure, 'text');
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta de los filtros')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal de Entrada')
            xlabel(app.UIAxes_2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_2, 'Amplitud')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Señal de Salida')
            xlabel(app.UIAxes_3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_3, 'Amplitud')
            app.GenerarButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerarButton_2.Text = 'Generar';
            app.GuardarVariablesEditFieldLabel = uilabel(app.UIFigure);
            app.GuardarVariablesEditFieldLabel.Text = 'Guardar Variables';
            app.GuardarVariablesEditField = uieditfield(app.UIFigure, 'text');
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            app.wavLabel = uilabel(app.UIFigure);
            app.wavLabel.Text = '.wav';
            app.GenerarSealdelFiltroButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdelFiltroButton.Text = 'Generar Señal del Filtro';
            app.GenerarSealdeEntradaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealdeEntradaButtonPushed, true);
            app.GenerarSealdeEntradaButton.Text = 'Generar Señal de Entrada';
            app.GenerarSealdeSalidaButton = uibutton(app.UIFigure, 'push');
            app.GenerarSealdeSalidaButton.Text = 'Generar Señal de Salida';
            app.AplicarCambiosButton = uibutton(app.UIFigure, 'push');
            app.AplicarCambiosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarCambiosButtonPushed, true);
            app.AplicarCambiosButton.Text = 'Aplicar Cambios';
            app.AplicarCambiosButton_2 = uibutton(app.UIFigure, 'push');
            app.AplicarCambiosButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarCambiosButton_2Pushed, true);
            app.AplicarCambiosButton_2.Text = 'Aplicar Cambios';
            app.SwitchFiltro1 = uiswitch(app.UIFigure, 'slider');
            app.SwitchFiltro2 = uiswitch(app.UIFigure, 'slider');
            app.FrecuenciadecorteSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciadecorteSpinnerLabel.Text = 'Frecuencia de corte';
            app.Frecuenciadecorte1 = uispinner(app.UIFigure);
            app.Frecuenciadecorte1.Step = 0.5;
            app.Frecuenciadecorte1.Value = 150;
            app.GananciaSpinner_2Label = uilabel(app.UIFigure);
            app.GananciaSpinner_2Label.Text = 'Ganancia';
            app.Ganancia2 = uispinner(app.UIFigure);
            app.Ganancia2.Step = 0.5;
            app.Ganancia2.Value = 1;
            app.FrecuenciadecorteSpinner_2Label = uilabel(app.UIFigure);
            app.FrecuenciadecorteSpinner_2Label.Text = 'Frecuencia de corte';
            app.Frecuenciadecorte2 = uispinner(app.UIFigure);
            app.Frecuenciadecorte2.Step = 0.5;
            app.Frecuenciadecorte2.Value = 150;
            app.AlfaLabel = uilabel(app.UIFigure);
            app.AlfaLabel.Text = 'Alfa';
            app.Alfa = uispinner(app.UIFigure);
            app.Alfa.Step = 0.5;
            app.Alfa.Value = 1;
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.Delay = uislider(app.UIFigure);
            app.Delay.Limits = [0 11025];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end